classdef planner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        start        matlab.ui.control.DropDown
        Label_2      matlab.ui.control.Label
        num_topics   matlab.ui.control.NumericEditField
        Button       matlab.ui.control.Button
        UITable      matlab.ui.control.Table
        Label_3      matlab.ui.control.Label
        period_tpye  matlab.ui.control.DropDown
        AboutBotton  matlab.ui.control.Button
        Label_5      matlab.ui.control.Label
        TextArea     matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            start_day_str = app.start.Value;
            switch start_day_str
                case 'Mon.'
                    start_day = 1;
                case 'Tue.'
                    start_day = 2;
                case 'Wes.'
                    start_day = 3;
                case 'Thu.'
                    start_day = 4;
                case 'Fri.'
                    start_day = 5;
                case 'Sat.'
                    start_day = 6;
                case 'Sun.'
                    start_day = 7;
            end
            num_list = app.num_topics.Value;
            if num_list ~= fix(num_list)
                uialert(app.UIFigure,'请输入正确的总单元数','Error',...
                'Icon','error');
            end
            period_str = app.period_tpye.Value;
            period = str2num(period_str);
%             switch period_str
%                 case '精简'
%                     period = [0, 1, 4, 10];
%                 case '一般'
%                     period = [0, 1, 4, 7, 15];
%                 case '困难'
%                     period = [0, 1, 2, 4, 7, 15];
%             end
            max_data = start_day+num_list + max(period);
            num_p = length(period);
            C = cell(ceil(max_data / 7), 7);
            for idx_l = 1:num_list
                for idx_p = 1:num_p
                    idx_d = idx_l + (start_day - 1) + period(idx_p);
                    if idx_d > 7
                        idx_w = ceil(idx_d / 7);
                        idx_d = mod(idx_d, 7);
                        if idx_d == 0
                            idx_d = 7;
                        end
                    else
                        idx_w = 1;
                    end
%                     str = sprintf('%s %02d', 'Unit', idx_l);
                    str = sprintf('%d', idx_l);
                    if isempty(C{idx_w, idx_d})
                        C{idx_w, idx_d} = ['Unit ', str];
                    else
                        C{idx_w, idx_d} = [C{idx_w, idx_d}, '+', str];
                    end
                end
            end
            max_idx_w = idx_w;
            T = cell2table(C);
            row_names = cell(1,max_idx_w);
            for idx_ww = 1:max_idx_w
                row_names_temp = sprintf('%s %02d', 'Week', idx_ww);
                row_names{1,idx_ww} = row_names_temp;
            end
            app.UITable.Data = T;
            app.UITable.ColumnName = {'Mon.', 'Tue.', 'Wes.', 'Thu.', 'Fri.', 'Sat.', 'Sun.'};
            app.UITable.RowName = row_names;
        end

        % Callback function
        function HelpButtonPushed(app, event)
            message = sprintf('%s\n\n%s:\n%s\n\n%s:\n%s', ...
                '根据艾宾浩斯遗忘规律制作复习周期', ...
                '短期记忆节点', '5分钟，30分钟，12小时', ...
                '长期记忆节点', '1天，2天，4天，7天，15天');
            uialert(app.UIFigure,message,'Help',...
                'Icon','info');
        end

        % Button pushed function: AboutBotton
        function AboutBottonPushed(app, event)
            message = sprintf('%s\n\n%s:\n%s\n\n%s:\n%s', ...
                'Tongji University', ...
                '半桶水', 'zeroLFL', ...
                'E-mail', '569536137@qq.com');
            uialert(app.UIFigure,message,'About',...
                'Icon','info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '开始日期';
            app.start = uidropdown(app.UIFigure);
            app.start.Items = {'Mon.', 'Tue.', 'Wes.', 'Thu.', 'Fri.', 'Sat.', 'Sun.'};
            app.start.Value = 'Mon.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '总单元数';
            app.num_topics = uieditfield(app.UIFigure, 'numeric');
            app.num_topics.Limits = [1 Inf];
            app.num_topics.Tooltip = {'请输入要复习的总单元数'};
            app.num_topics.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'生成'; '复习日期表'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Mon.'; 'Tue.'; 'Wes.'; 'Thu.'; 'Fri.'; 'Sat.'; 'Sun.'};
            app.UITable.RowName = {};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '记忆节点';
            app.period_tpye = uidropdown(app.UIFigure);
            app.period_tpye.Items = {'[0, 1, 4, 10]', '[0, 1, 4, 7, 15]', '[0, 1, 2, 4, 7, 15]'};
            app.period_tpye.Editable = 'on';
            app.period_tpye.Tooltip = {'记忆节点指长期记忆的天数关键点'};
            app.period_tpye.Value = '[0, 1, 4, 10]';
            app.AboutBotton = uibutton(app.UIFigure, 'push');
            app.AboutBotton.ButtonPushedFcn = createCallbackFcn(app, @AboutBottonPushed, true);
            app.AboutBotton.Icon = 'glass_water_128px.png';
            app.AboutBotton.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '复习日期表';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'根据艾宾浩斯遗忘规律制作复习周期'; ''; '短期记忆节点：5分钟后，30分钟后，12小时后'; ''; '长期记忆节点：1天后，2天后，4天后，7天后，15天后'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = planner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end